classdef SignalVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SignalVisualizerMenu          matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        GenerarMenu                   matlab.ui.container.Menu
        TonopuroMenu                  matlab.ui.container.Menu
        SntesisdearmnicosMenu         matlab.ui.container.Menu
        SealesperidicasconocidasMenu  matlab.ui.container.Menu
        OndacuadradaMenu              matlab.ui.container.Menu
        DientedesierraMenu            matlab.ui.container.Menu
        PulsodeRosenbergMenu          matlab.ui.container.Menu
        RuidoMenu                     matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        PlayButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp % Description
        time % Description
        fs = 48000; % Description
        % Description
    end
    
    properties (Access = public)
        signal = []; % Description
        spectrum = []; % Description
        freq = []; % Description
    end
    
    methods (Access = public)
        
        function updatePlot(app,signal,t,varargin)
            app.signal = signal;
            app.time = t;
            
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            plot(app.UIAxes,app.time,app.signal,'b');
            
            % Ajusta el eje Y a la señal
            if ~isempty(varargin)
                app.UIAxes.YLim = [0,1];
            else
                if(max(app.signal)>1)
                    app.UIAxes.YLim = [-ceil(max(app.signal)),ceil(max(app.signal))];
                else
                    app.UIAxes.YLim = [-1,1];
                end
            end
            
            % Ajusta el eje X a las frecuencias relevantes del fft
            plot(app.UIAxes2,app.freq,app.spectrum,'b');
            xa = find(app.spectrum > 10^-3,1);
            xb = find(app.spectrum > 10^-3,1,'last');
            
            if round(xa*0.8) > 0
                xa = round(xa*0.8);
            end
            
            if round(xb*1.2) < 24000
                xb = round(xb*1.2);
            end
            
            app.UIAxes2.XLim = [xa,xb];
            axtoolbar(app.UIAxes2,{'export','pan','datacursor','zoomin','zoomout','restoreview'});
            
            app.PlayButton.Enable = 'on';
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 634;
            originalh = 554;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(~,element)
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalw = element.Position(3);
            originalh = element.Position(4);
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            newhpos = round(originalhpos*screenw/1280);
            newvpos = round(originalvpos*screenh/1024);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(screenw*screenh)/(1280*1024));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resizeWindow(app);
            
            for i = 1:length(app.UIFigure.Children)
                if ~strcmp(app.UIFigure.Children(i).Type,'uimenu')
                    app.resizeelement(app.UIFigure.Children(i));
                end
            end
                       
        end

        % Menu selected function: TonopuroMenu
        function TonopuroMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = tone_App(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButton.Enable = 'off';
            
            if(max(abs(app.signal)) > 1)
                signalAux = app.signal./max(app.signal);
            else
                signalAux = app.signal;
            end
            
            sound(signalAux,app.fs);
            
            app.PlayButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp);
            
            delete(app)
        end

        % Menu selected function: SntesisdearmnicosMenu
        function SntesisdearmnicosMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = harmonics_synthesis_App(app);
        end

        % Menu selected function: OndacuadradaMenu
        function OndacuadradaMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = square_App(app);
        end

        % Menu selected function: DientedesierraMenu
        function DientedesierraMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = sawtooth_App(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.DialogApp);
            
            delete(app)
        end

        % Menu selected function: PulsodeRosenbergMenu
        function PulsodeRosenbergMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = rosenberg_App(app);
        end

        % Menu selected function: RuidoMenu
        function RuidoMenuSelected(app, event)
            app.SignalVisualizerMenu.Enable = 'off';
            app.GenerarMenu.Enable = 'off';
            app.PlayButton.Enable = 'off';
            
            app.DialogApp = noise_app(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SignalVisualizerMenu = uimenu(app.UIFigure);
            app.SignalVisualizerMenu.Text = 'Signal Visualizer';
            app.ExitMenu = uimenu(app.SignalVisualizerMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GenerarMenu = uimenu(app.UIFigure);
            app.GenerarMenu.Text = 'Generar';
            app.TonopuroMenu = uimenu(app.GenerarMenu);
            app.TonopuroMenu.MenuSelectedFcn = createCallbackFcn(app, @TonopuroMenuSelected, true);
            app.TonopuroMenu.Text = 'Tono puro';
            app.SntesisdearmnicosMenu = uimenu(app.GenerarMenu);
            app.SntesisdearmnicosMenu.MenuSelectedFcn = createCallbackFcn(app, @SntesisdearmnicosMenuSelected, true);
            app.SntesisdearmnicosMenu.Text = 'Síntesis de armónicos';
            app.SealesperidicasconocidasMenu = uimenu(app.GenerarMenu);
            app.SealesperidicasconocidasMenu.Text = 'Señales periódicas conocidas';
            app.OndacuadradaMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.OndacuadradaMenu.MenuSelectedFcn = createCallbackFcn(app, @OndacuadradaMenuSelected, true);
            app.OndacuadradaMenu.Text = 'Onda cuadrada';
            app.DientedesierraMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.DientedesierraMenu.MenuSelectedFcn = createCallbackFcn(app, @DientedesierraMenuSelected, true);
            app.DientedesierraMenu.Text = 'Diente de sierra';
            app.PulsodeRosenbergMenu = uimenu(app.SealesperidicasconocidasMenu);
            app.PulsodeRosenbergMenu.MenuSelectedFcn = createCallbackFcn(app, @PulsodeRosenbergMenuSelected, true);
            app.PulsodeRosenbergMenu.Text = 'Pulso de Rosenberg';
            app.RuidoMenu = uimenu(app.GenerarMenu);
            app.RuidoMenu.MenuSelectedFcn = createCallbackFcn(app, @RuidoMenuSelected, true);
            app.RuidoMenu.Text = 'Ruido';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FFT')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Amplitud')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end